classdef Image_Viewing_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProcessAllImagesButton     matlab.ui.control.Button
        HeatmapOpacitySlider       matlab.ui.control.Slider
        HeatmapOpacitySliderLabel  matlab.ui.control.Label
        KernelsEditField           matlab.ui.control.NumericEditField
        KernelsEditFieldLabel      matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        ImageType                  matlab.ui.control.DropDown
        ImageTypeDropDownLabel     matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        ProcessImageButton         matlab.ui.control.Button
        FilterDropDown             matlab.ui.control.DropDown
        FilterDropDownLabel        matlab.ui.control.Label
        LoadImageButton            matlab.ui.control.Button
        HeatmapAxes                matlab.ui.control.UIAxes
        high_res_axes              matlab.ui.control.UIAxes
        widefield_axes             matlab.ui.control.UIAxes
    end

    %Need to display z-stacks, zoom in, and fix ROI position
    
    properties (Access = private)
        widefield_img;
        adjusted_image; %Widefield image with brightness and contrast adjusted
        widefield_notes;
        widefield_grid; %Stores image locations for low-res images
        widefield_mag = 4; %Magnification of the widefield image
        px_size_mm = 4.25; %Size of a pixel unmagnified

        main_folder; %Path to main folder for widefield and high-res images
        subfolder; %Path to subfolder for high-res images

        high_res_images; %Image datastore for high-res images in subfolder
        retardance_images; %Image datastore for retardance images in subfolder
        rgb_images; %Image datastore for RGB images in subfolder
        phi_images; %Image datastore for phi images in subfolder
        
        high_res_notes;
        high_res_grid; %Stores image locations for high-res images
        high_res_mag; %Magnification of the high-res image

        filtered_img;
        filtered_widefield;
        right_box_note; %Note of what's in the high res axes
        circstats; %Structure of circular statistics

        camera_px_num = [2960 2960];
        image_size_px;
        overlap;

        %Z-stack information
        zstack; %The actual z-stack (current one for viewing)
        zoom_mode; %For zooming into the z-stack images
        stack_size; %Number of planes in the z-stack
        
        %Overlay information
        patch_handle;
        patchx;
        patchy;
        hImage_widefield; %Handle for widefield image for selecting ROIs
        hImage_high_res; %Handle for high-res image for displaying z-stack planes
        hImage_heatmap; %Handle for heatmaps displayed over the high-res axes
        center;
        which_box; %The patch box of the currently-clicked image
        green_patch; %Handle for the green patch
    end
    
    methods (Access = private)
        
        function [image, zstack_size] = load_zstack(~, filename)
                info = imfinfo(filename); % get file info
                
                % Load images (check if it is a multipage tiff file)
                if (strcmp(convertCharsToStrings(info(1).Format), "tiff")) || (strcmp(convertCharsToStrings(info(1).Format), "tif"))
                    tstack = Tiff(info(1).Filename);
                    im = tstack.read();
    
                    stackSize = [info(1).Width, info(1).Height, info(1).SamplesPerPixel, length(info)];
                    stackSize = squeeze(stackSize);
                    image = zeros(stackSize,'like',im);
                    image(:,:,:,1)  = imrotate(tstack.read(), 90);
                    for n = 2:length(info)
                        drawnow
                        tstack.nextDirectory()
                        image(:,:,:,n) = imrotate(tstack.read(), 90);
                    end
                else
                    %other file formats dont support multipage Z stacks so just
                    %load with imread 
                    image = flip(imread(info(1).Filename)');
                end
                zstack_size = length(info);
        end
    
        function results = circstat_calc(app, retardance, orientation, varargin)
            %maxcut,st,roimask_a,plots,dupe
            % Setup optional arguments
            if nargin <2
                error('Need retardance and orientation')
            end
            defaultMaxcut=length(retardance(1,:)); %#ok<NASGU> 
            defaultst=100; %#ok<NASGU> 
            defaultRoiMask=ones(size(retardance));
            defaultPlots='n'; %#ok<NASGU> 
            defaultDupe=0; %#ok<NASGU> 
            defaultListArgs={'SD','Anjes','Rao','CircCorr','Kuiper', 'CircVar'};
            
            p=inputParser;
            
            addRequired(p,'retardance',@isnumeric)
            addRequired(p,'orientation',@isnumeric)
            addParameter(p,'step',2000,@isnumeric)
            addParameter(p,'rangex',[1:length(retardance(1,:,1,1))],@isnumeric)
            addParameter(p,'rangey',[1:length(retardance(1,:,1,1))],@isnumeric) %#ok<*NBRAK> 
            addParameter(p,'roimask',defaultRoiMask,@isnumeric)
            addParameter(p,'plots','n',@isstring)
            addParameter(p,'ListTests',defaultListArgs,@iscellstr)
            addParameter(p,'Duplicate',0,@isnumeric)
            
            parse(p,retardance,orientation,varargin{:})
            
            retardance=p.Results.retardance;
            orientation=p.Results.orientation;
            st=p.Results.step;
            roimask=p.Results.roimask;
            plots=p.Results.plots; %#ok<NASGU> 
            listtest=p.Results.ListTests;
            dupe=p.Results.Duplicate;
            rangey=p.Results.rangey;
            rangex=p.Results.rangex;
            
            if st>(max(rangex)-min(rangex)) && st>(max(rangey)-min(rangey))
                error('step must be less than range iterating over')
            end
            
            orientation(isnan(orientation))=.0001;
            orientation(orientation==0)=.0001;
            
            % Inalize Variables
            llrange = [1:5:length(retardance(1,1,1,:))];
            jjrange = [1:st:max(rangey)-st-min(rangey)+1];

            orlist=cell(length(rangex),1);
            rtlist=cell(length(rangex),1);
            %sp=[1:st:max(rangex)-st];
            ar_mean = zeros(length(jjrange), length(llrange));
            ar_SD = zeros(length(jjrange), length(llrange));
            ar_p_otest = zeros(length(jjrange), length(llrange));
            ar_m_otest = zeros(length(jjrange), length(llrange));
            ar_p_rao = zeros(length(jjrange), length(llrange));
            ar_U_rao = zeros(length(jjrange), length(llrange));
            ar_ccor = zeros(length(jjrange), length(llrange));
            ar_p_ccor = zeros(length(jjrange), length(llrange));
            ar_j_kuip = zeros(length(jjrange), length(llrange));
            ar_mnorm = zeros(length(jjrange), length(llrange));
            ar_circvar = zeros(length(jjrange), length(llrange));
            
            orientation=2*orientation; %double orientation since our orientations are from 0 to pi and circstat assumes orientations from 0 to 2pi
            for ll = llrange
                i = 0;
                lplace = (ll+4)/5;
                rcrop=retardance(rangex,rangey,1,ll);
                retardance_1norm=(rcrop-min(rcrop(:)))/max(rcrop(:));
                retardance_1norm(retardance_1norm==0)=.0001;
                for kk=[1:st:max(rangex)-st-min(rangex)+1]
                    
                  
                   % for jj=[min(rangex):st:max(rangex)-st] %for loops to control kernal
                     for jj = jjrange %for loops to control kernal
                        
                    % Genrate orientation and retardance of just kernal
                        i=i+1;
                        %creating masked and kerneled regions of the orientation and
                        %retardance
                        
                            orlist{i,1}=roimask(jj:jj+st,kk:kk+st).*orientation(jj:jj+st,kk:kk+st,1,ll);
                            rtlist{i,1}=roimask(jj:jj+st,kk:kk+st).*retardance_1norm(jj:jj+st,kk:kk+st);
                            
                            if dupe>0
                                nL2=repelem(nonzeros(orlist{i,1}(:)),round(nonzeros(rtlist{i,1}(:))*dupe)); %set up "retardance weighted" orientation list removing the masked values, now set to 0
                           
                                unorient=linspace(0,2*pi,length(nonzeros(nL2)));  %setup ideal orientation with equally spaced and weighted components
                            end
                            if dupe==0
                                nL2=nonzeros(orlist{i,1}(:));
                                unorient=linspace(0,2*pi,length(nL2));
                            end
   %                         if plots=='y' %show histograms both circular and linear
   %                             figure(),circ_plot(nL2,'hist',[],20,true,true,'linewidth',2,'color','r')
   %                             figure(), histogram(nL2)
   %                             title('Base Angle Histogram')
   %                             xlabel('Radians')
   %                         end
                
                            % Evaluate all the circular tests with weighting if avaliable or appropriate
                            if ismember('Anjes',listtest)
                                [ar_p_otest(i, lplace),ar_m_otest(i, lplace)]=circ_otest(nL2);
                                ar_mnorm(i, lplace)=2*ar_m_otest(i, lplace)/length(nL2);
                            end
                            if ismember('Rao',listtest)
                                [ar_p_rao(i, lplace), ar_U_rao(i, lplace), ~] = circ_raotest(nL2);
                            end
                            if ismember('CircCorr',listtest)
                                [ar_ccor(i, lplace),ar_p_ccor(i, lplace)]=circ_corrcc(sort(nL2),unorient);
                            end
                            if ismember('Kuiper',listtest)
                                ar_j_kuip(i, lplace)=circ_kuipertest(nL2,unorient);
                            end
                            if ismember('SD',listtest)
                                ar_mean(i, lplace)=circ_mean(nonzeros(orlist{i,1}(:)),nonzeros(rtlist{i,1}(:)));
                                ar_SD(i, lplace)=circ_std(nonzeros(orlist{i,1}(:)),nonzeros(rtlist{i,1}(:)));
                            end
                            if ismember('CircVar',listtest)
                                ar_circvar(i, lplace) = circ_var(nonzeros(orlist{i,1}(:)), nonzeros(rtlist{i,1}(:)), [], 'all');
                            end
                
                    end
                end
            end
            ar_p_otest = mean(ar_p_otest, 2);
            ar_m_otest = mean(ar_m_otest, 2);
            ar_mnorm = mean(ar_mnorm, 2);
            ar_p_rao = mean(ar_p_rao, 2);
            ar_U_rao = mean(ar_U_rao, 2);
            ar_ccor = mean(abs(ar_ccor), 2);
            ar_p_ccor = mean(ar_p_ccor, 2);
            ar_j_kuip = mean(ar_j_kuip, 2);
            ar_mean = mean(ar_mean, 2);
            ar_SD = mean(ar_SD, 2);
            ar_circvar = mean(ar_circvar, 2);
            %%
            if dupe == 0
              ccor_heatmap = reshape(ar_ccor, app.KernelsEditField.Value, []);
              results.ccor_heatmap = imresize(ccor_heatmap, app.camera_px_num);
              anjes_heatmap = reshape(ar_mnorm, app.KernelsEditField.Value, []);
              results.anjes_heatmap = imresize(anjes_heatmap, app.camera_px_num);
              sd_heatmap = reshape(ar_SD, app.KernelsEditField.Value, []);
              results.sd_heatmap = imresize(sd_heatmap, app.camera_px_num);
              circvar_heatmap = reshape(ar_circvar, app.KernelsEditField.Value, []);
              results.circvar_heatmap = imresize(circvar_heatmap, app.camera_px_num);
            end
            % Find mean of the kernals (excluding NaN elements which can occur when everything is 0, which will happen if a kernal completely encompases a masked area, this is poorly tested so let me know if this has issues)
            results.ccor=mean(rmmissing(ar_ccor));
            results.pccor=mean(rmmissing(ar_p_ccor));
            results.Jkuip=mean(rmmissing(ar_j_kuip));
            results.m=mean(rmmissing(ar_m_otest));
            results.SD=mean(rmmissing(ar_SD));
            results.mean=mean(rmmissing(ar_mean));
            results.movern2=mean(rmmissing(ar_mnorm));
            results.panjes=mean(rmmissing(ar_p_otest));
            results.urao=mean(rmmissing(ar_U_rao));
            results.prao=mean(rmmissing(ar_p_rao));
            if length(ar_ccor)>1
                results.ccorstd=std(rmmissing(ar_ccor));
                results.pccorstd=std(rmmissing(ar_p_ccor));
            end
            if length(ar_ccor)==1 || isempty(ar_ccor)
                results.ccorstd=nan;
                results.pccorstd=nan;
            end
            if length(ar_j_kuip)>1
                results.Jkuipstd=std(rmmissing(ar_j_kuip));
            end
            if length(ar_j_kuip)==1 || isempty(ar_j_kuip)
                results.Jkuipstd=nan;
            end
            if length(ar_m_otest)>1
                results.mstd=std(rmmissing(ar_m_otest));
                results.panjesstd=std(rmmissing(ar_p_otest));
                results.movern2std=std(rmmissing(ar_mnorm));
            end
            if length(ar_m_otest)==1 || isempty(ar_m_otest)
                results.mstd=nan;
                results.panjesstd=nan;
                results.movern2std=nan;
            end
            if length(ar_SD)>1
                results.SDstd=std(rmmissing(ar_SD));
                results.meanstd=std(rmmissing(ar_mean));
            end
            if length(ar_SD)==1 || isempty(ar_SD)
                results.SDstd=nan;
                results.meanstd=nan;
            end
            if length(ar_U_rao)>1
                results.uraostd=std(rmmissing(ar_U_rao));
                results.praostd=std(rmmissing(ar_p_rao));
            end
            if length(ar_U_rao)==1 || isempty(ar_U_rao)
                results.uraostd=nan;
                results.praostd=nan;
            end 
        end
 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            linkaxes([app.high_res_axes, app.HeatmapAxes]);
            axis(app.HeatmapAxes, 'image')
        end

        % Button pushed function: LoadImageButton
        function Load_Image(app, event)
            try
                %Gets folder
                app.main_folder = string(uigetdir);
    
                %Shows widefield image
                app.Label.Text = 'Loading Image...';
                drawnow;
                if isfile(app.main_folder + '\Fused.tif')
                    widefield_path = (app.main_folder + '\Fused.tif');
                else
                    widefield_path = (app.main_folder + '\Fused_CCP.tif');
                end
                app.widefield_img = imread(widefield_path);
                app.adjusted_image = flip(imadjust(app.widefield_img'));
                app.hImage_widefield = image(app.widefield_axes, app.adjusted_image, 'HitTest','off');
                colormap(app.widefield_axes, "gray")
                app.image_size_px = size(app.widefield_img);
                axis(app.widefield_axes, 'image')
                axis(app.high_res_axes, 'image')
    
                %Gets subfolder
                d = dir(app.main_folder);
                app.subfolder = d([d(:).isdir]);
                app.subfolder = app.subfolder(3).name;
                app.subfolder = (app.main_folder + '\' + app.subfolder);
    
                %Stores Data
                app.high_res_images = imageDatastore(app.subfolder);
                app.widefield_grid = load(app.main_folder + '\grid_coordinates.mat');
                app.high_res_grid = load(app.subfolder + '\grid_coordinates.mat');
                app.widefield_notes = readlines(app.main_folder + '\sample_notes.txt');
                app.high_res_notes = readlines(app.subfolder + '\sample_notes.txt');
                try
                app.retardance_images = imageDatastore(app.subfolder + '\qBRM\Ret');
                app.rgb_images = imageDatastore(app.subfolder + '\qBRM\RGB_norm');
                app.phi_images = imageDatastore(app.subfolder + '\qBRM\phi');
                catch
                    app.ImageType.Value = 'Raw';
                    app.ImageType.Items = 'Raw';
                    app.FilterDropDown.Value = 'Sobel';
                    app.FilterDropDown.Items = ['Sobel', 'Canny'];
                end
                %Forms meshes of only image locations in ROI
                app.patchx = zeros(4, length(app.high_res_images.Files));
                app.patchy = zeros(4, length(app.high_res_images.Files));
                patch_coords = load(app.subfolder + '\patch_coordinates.mat');
                [yhigh, xhigh] = size(app.high_res_grid.y_mesh);
                 for i = 1:length(app.patchx)
                    img_num = str2double(extractBetween(app.high_res_images.Files{i}, 'img_', '.tif'));
                    yplace = ceil(img_num/xhigh);
                    xplace = mod(img_num, xhigh);
                    if mod(yplace, 2) == 1
                        if xplace == 0
                            xplace = xhigh;
                        end
                    else
                        if xplace == 0
                            xplace = 1;
                        else
                            xplace = xhigh - xplace + 1;
                        end
                    end
                    coords_place = yhigh*(xplace-1) + yplace;
                    app.patchy(:,i) = app.image_size_px(2) - patch_coords.grid_patch_x(:,coords_place);
                    app.patchx(:,i) = patch_coords.grid_patch_y(:,coords_place);
                 end

                app.patch_handle = patch(app.patchx, app.patchy, 'red','EdgeColor', 'red', 'FaceColor', 'none', 'LineWidth', 2, 'Parent', app.widefield_axes);
                set(app.patch_handle, 'HitTest', 'off')


            %Error message
            catch ME
                error_message = sprintf('Error found in function %s() at line %d.\n\nError message:\n%s', ME.stack(1).name, ME.stack(1).line, ME.message);
                uiwait(warndlg(error_message));
            end
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            app.Label.Text = 'Processing Image...';
            drawnow;
            if app.right_box_note == "zstack"
                switch app.FilterDropDown.Value
                    case 'Sobel'
                        app.filtered_img = edge(im2gray(app.zstack(:,:,:,app.Slider.Value)), 'sobel');
                        imshow(app.filtered_img, 'Parent', app.high_res_axes)
                    case 'Canny'
                        app.filtered_img = edge(im2gray(app.zstack(:,:,:,app.Slider.Value)), 'canny');
                        imshow(app.filtered_img, 'Parent', app.high_res_axes)
                    case 'Circular Correlation'
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{app.which_box});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{app.which_box});
                        app.circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1));
                        app.hImage_heatmap = image(app.HeatmapAxes, app.circstats.ccor_heatmap.*255, 'AlphaData', app.HeatmapOpacitySlider.Value, 'HitTest', 'off');
                        colormap(app.HeatmapAxes, 'jet');
                        caxis(app.HeatmapAxes, [0 255]);
                        %colorbar(app.HeatmapAxes);
                    case 'Anjes'
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{app.which_box});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{app.which_box});
                        app.circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1));
                        app.hImage_heatmap = image(app.HeatmapAxes, app.circstats.anjes_heatmap.*255, 'AlphaData', app.HeatmapOpacitySlider.Value, 'HitTest', 'off');
                        colormap(app.HeatmapAxes, 'jet');
                        caxis(app.HeatmapAxes, [0 255]);
                        %colorbar(app.HeatmapAxes);
                    case 'Standard Deviation'
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{app.which_box});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{app.which_box});
                        app.circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1));
                        app.hImage_heatmap = image(app.HeatmapAxes, app.circstats.sd_heatmap.*255, 'AlphaData', app.HeatmapOpacitySlider.Value, 'HitTest', 'off');
                        colormap(app.HeatmapAxes, 'jet');
                        caxis(app.HeatmapAxes, [0 255]);
                        %colorbar(app.HeatmapAxes);
                    case 'Circular Variance'
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{app.which_box});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{app.which_box});
                        app.circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1));
                        app.hImage_heatmap = image(app.HeatmapAxes, app.circstats.circvar_heatmap.*30, 'AlphaData', app.HeatmapOpacitySlider.Value, 'HitTest', 'off');
                        colormap(app.HeatmapAxes, 'jet');
                        caxis(app.HeatmapAxes, [0 255]);
                        %colorbar(app.HeatmapAxes);
                end
                
            else
                switch app.FilterDropDown.Value
                    case 'Sobel'
                        app.filtered_img = edge(app.widefield_img, 'sobel');
                    case 'Canny'
                        app.filtered_img = edge(app.widefield_img, 'canny');
                end
                app.right_box_note = "filter";
                imshow(app.filtered_img, 'Parent', app.high_res_axes)
            end
        end

        % Button down function: widefield_axes
        function widefield_axesButtonDown(app, event)
            %Gets coordinates from clicking on the image
            coords = get(app.widefield_axes, 'CurrentPoint');
            coords = coords(1,1:2);

            %Finds coordinates of patch boxes
            top = app.patchy(1, :);
            bot = app.patchy(2, :);
            right = app.patchx(4, :);
            left = app.patchx(1, :);

            topcheck = top > coords(2);
            botcheck = bot < coords(2);
            rightcheck = right > coords(1);
            leftcheck = left < coords(1);
            logvec = (topcheck.*botcheck.*leftcheck.*rightcheck);

            app.Slider.Value = 1;
            if sum(logvec)
                delete(app.green_patch);
                app.Label.Text = 'Displaying high-res image...';
                drawnow;
                app.which_box = find(logvec, 1);
                switch app.ImageType.Value
                    case 'Raw'
                        [app.zstack, app.stack_size] = app.load_zstack(app.high_res_images.Files{app.which_box});
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'default');
                        app.hImage_high_res.CDataMapping = 'direct';
                    case 'Retardance'
                        [app.zstack, app.stack_size] = app.load_zstack(app.retardance_images.Files{app.which_box});
                        app.zstack = im2uint8(mat2gray(app.zstack));
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'gray');
                        %app.hImage_high_res.CDataMapping = 'scaled';
                    case 'RGB'
                        [app.zstack, app.stack_size] = app.load_zstack(app.rgb_images.Files{app.which_box});
                        
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'default');
                        app.hImage_high_res.CDataMapping = 'direct';
                    case 'Phi'
                        [app.zstack, app.stack_size] = app.load_zstack(app.phi_images.Files{app.which_box});
                        app.zstack = im2uint8(mat2gray(app.zstack));
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'gray');
                        %app.hImage_high_res.CDataMapping = 'scaled';
                end
                app.right_box_note = "zstack";
                app.Slider.Limits = [1, app.stack_size];
                app.Slider.MajorTicks = [1:app.stack_size];
                app.green_patch = patch(app.patchx(:, app.which_box), app.patchy(:, app.which_box), 'yellow','EdgeColor', 'yellow', 'FaceColor', 'none', 'LineWidth', 2, 'Parent', app.widefield_axes);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            app.Slider.Value = value;

            if app.right_box_note == "zstack"
                app.hImage_high_res.CData = app.zstack(:,:,:,app.Slider.Value);
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            %verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            try
                app.Slider.Value = app.Slider.Value - verticalScrollCount;
                if app.right_box_note == "zstack"
                app.hImage_high_res.CData = app.zstack(:,:,:,app.Slider.Value);
                end
            catch
            end
        end

        % Value changed function: ImageType
        function ImageTypeValueChanged(app, event)
            if app.right_box_note == "zstack"
                app.Label.Text = 'Changing image type...';
                drawnow;
                switch app.ImageType.Value
                    case 'Raw'
                        [app.zstack, app.stack_size] = app.load_zstack(app.high_res_images.Files{app.which_box});
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'default');
                        app.hImage_high_res.CDataMapping = 'direct';
                    case 'Retardance'
                        [app.zstack, app.stack_size] = app.load_zstack(app.retardance_images.Files{app.which_box});
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'gray');
                        app.hImage_high_res.CDataMapping = 'scaled';
                    case 'RGB'
                        [app.zstack, app.stack_size] = app.load_zstack(app.rgb_images.Files{app.which_box});
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'default');
                        app.hImage_high_res.CDataMapping = 'direct';
                    case 'Phi'
                        [app.zstack, app.stack_size] = app.load_zstack(app.phi_images.Files{app.which_box});
                        app.hImage_high_res = image(app.high_res_axes, app.zstack(:,:,:,app.Slider.Value), 'HitTest','off');
                        colormap(app.high_res_axes, 'gray');
                        app.hImage_high_res.CDataMapping = 'scaled';
                end
            end
        end

        % Value changed function: HeatmapOpacitySlider
        function HeatmapOpacitySliderValueChanged(app, event)
            value = app.HeatmapOpacitySlider.Value;
            try
                app.hImage_heatmap.AlphaData = value;
            catch
            end
        end

        % Button pushed function: ProcessAllImagesButton
        function ProcessAllImagesButtonPushed(app, event)
            app.Label.Text = 'Processing Images...';
            drawnow;
            switch app.FilterDropDown.Value
                case 'Sobel'
                    app.filtered_widefield = edge(app.adjusted_image, 'sobel');
                    imshow(app.filtered_widefield, 'Parent', app.widefield_axes)
                case 'Canny'
                    app.filtered_widefield = edge(app.adjusted_image, 'canny');
                    imshow(app.filtered_widefield, 'Parent', app.widefield_axes)
                case 'Circular Correlation'
                    app.overlap = str2double(extractBetween(app.high_res_notes(10), 'Overlap: ', ';'))./100;
                    for i = 1:length(app.high_res_images.Files)    
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{i});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{i});
                        wide_circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1), 'Duplicate', 25);
                        xdiff = app.patchx(4) - app.patchx(1);
                        ydiff = app.patchy(1) - app.patchy(2);
                        heatpatchx = zeros(4, 1);
                        heatpatchy = zeros(4, 1);
                        heatpatchx(1:2) = app.patchx(1:2, i) + xdiff.*app.overlap./2;
                        heatpatchx(3:4) = app.patchx(3:4, i) - xdiff.*app.overlap./2;
                        heatpatchy(1) = app.patchy(1, i) - ydiff.*app.overlap./2;
                        heatpatchy(2:3) = app.patchy(2:3, i) + ydiff.*app.overlap./2;
                        heatpatchy(4) = app.patchy(4, i) - ydiff.*app.overlap./2;
                        if wide_circstats.ccor > 1
                            wide_circstats.ccor = 1;
                        elseif wide_circstats.ccor < 0
                            wide_circstats.ccor = 0;
                        end
                        patch(heatpatchx, heatpatchy, 'red', 'EdgeColor', 'none', 'FaceColor', [wide_circstats.ccor 0 1-wide_circstats.ccor], 'FaceAlpha', app.HeatmapOpacitySlider.Value, 'Parent', app.widefield_axes);
                        drawnow;
                    end
                case 'Anjes'
                    app.overlap = str2double(extractBetween(app.high_res_notes(10), 'Overlap: ', ';'))./100;
                    for i = 1:length(app.high_res_images.Files)    
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{i});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{i});
                        wide_circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1), 'Duplicate', 25);
                        xdiff = app.patchx(4) - app.patchx(1);
                        ydiff = app.patchy(1) - app.patchy(2);
                        heatpatchx = zeros(4, 1);
                        heatpatchy = zeros(4, 1);
                        heatpatchx(1:2) = app.patchx(1:2, i) + xdiff.*app.overlap./2;
                        heatpatchx(3:4) = app.patchx(3:4, i) - xdiff.*app.overlap./2;
                        heatpatchy(1) = app.patchy(1, i) - ydiff.*app.overlap./2;
                        heatpatchy(2:3) = app.patchy(2:3, i) + ydiff.*app.overlap./2;
                        heatpatchy(4) = app.patchy(4, i) - ydiff.*app.overlap./2;
                        if wide_circstats.movern2 > 1
                            wide_circstats.movern2 = 1;
                        elseif wide_circstats.movern2 < 0
                            wide_circstats.movern2 = 0;
                        end
                        patch(heatpatchx, heatpatchy, 'red', 'EdgeColor', 'none', 'FaceColor', [wide_circstats.movern2 0 1-wide_circstats.movern2], 'FaceAlpha', app.HeatmapOpacitySlider.Value, 'Parent', app.widefield_axes);
                        drawnow;
                    end
                case 'Standard Deviation'
                    app.overlap = str2double(extractBetween(app.high_res_notes(10), 'Overlap: ', ';'))./100;
                    for i = 1:length(app.high_res_images.Files)    
                        [tempret, ~] = app.load_zstack(app.retardance_images.Files{i});
                        [tempphi, ~] = app.load_zstack(app.phi_images.Files{i});
                        wide_circstats = circstat_calc(app, tempret, tempphi, 'st', app.camera_px_num(1)/(app.KernelsEditField.Value+1));
                        xdiff = app.patchx(4) - app.patchx(1);
                        ydiff = app.patchy(1) - app.patchy(2);
                        heatpatchx = zeros(4, 1);
                        heatpatchy = zeros(4, 1);
                        heatpatchx(1:2) = app.patchx(1:2, i) + xdiff.*app.overlap./2;
                        heatpatchx(3:4) = app.patchx(3:4, i) - xdiff.*app.overlap./2;
                        heatpatchy(1) = app.patchy(1, i) - ydiff.*app.overlap./2;
                        heatpatchy(2:3) = app.patchy(2:3, i) + ydiff.*app.overlap./2;
                        heatpatchy(4) = app.patchy(4, i) - ydiff.*app.overlap./2;
                        if wide_circstats.SD > 1
                            wide_circstats.SD = 1;
                        elseif wide_circstats.SD < 0
                            wide_circstats.SD = 0;
                        end
                        patch(heatpatchx, heatpatchy, 'red', 'EdgeColor', 'none', 'FaceColor', [wide_circstats.SD 0 1-wide_circstats.SD], 'FaceAlpha', app.HeatmapOpacitySlider.Value, 'Parent', app.widefield_axes);
                        drawnow;
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.widefield_axes = uiaxes(app.UIFigure);
            title(app.widefield_axes, 'Widefield Image')
            app.widefield_axes.XTick = [];
            app.widefield_axes.YTick = [];
            app.widefield_axes.ButtonDownFcn = createCallbackFcn(app, @widefield_axesButtonDown, true);
            app.high_res_axes = uiaxes(app.UIFigure);
            title(app.high_res_axes, 'High-res View')
            zlabel(app.high_res_axes, 'Z')
            app.high_res_axes.XTick = [];
            app.high_res_axes.YTick = [];
            app.HeatmapAxes = uiaxes(app.UIFigure);
            title(app.HeatmapAxes, 'High-res View')
            zlabel(app.HeatmapAxes, 'Z')
            app.HeatmapAxes.CLim = [0 255];
            app.HeatmapAxes.XTick = [];
            app.HeatmapAxes.YTick = [];
            app.HeatmapAxes.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Image, true);
            app.LoadImageButton.Text = 'Load Image';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'Circular Variance', 'Circular Correlation', 'Standard Deviation', 'Anjes', 'Sobel', 'Canny'};
            app.FilterDropDown.Value = 'Circular Variance';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 15];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.ImageTypeDropDownLabel = uilabel(app.UIFigure);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageType = uidropdown(app.UIFigure);
            app.ImageType.Items = {'Retardance', 'Raw', 'RGB', 'Phi'};
            app.ImageType.ValueChangedFcn = createCallbackFcn(app, @ImageTypeValueChanged, true);
            app.ImageType.Value = 'Retardance';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.KernelsEditFieldLabel = uilabel(app.UIFigure);
            app.KernelsEditFieldLabel.Text = 'Kernels';
            app.KernelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.KernelsEditField.Value = 20;
            app.HeatmapOpacitySliderLabel = uilabel(app.UIFigure);
            app.HeatmapOpacitySliderLabel.Text = 'Heatmap Opacity';
            app.HeatmapOpacitySlider = uislider(app.UIFigure);
            app.HeatmapOpacitySlider.Limits = [0 1];
            app.HeatmapOpacitySlider.MajorTicks = [0 0.5 1];
            app.HeatmapOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @HeatmapOpacitySliderValueChanged, true);
            app.HeatmapOpacitySlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HeatmapOpacitySlider.Value = 0.3;
            app.ProcessAllImagesButton = uibutton(app.UIFigure, 'push');
            app.ProcessAllImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllImagesButtonPushed, true);
            app.ProcessAllImagesButton.Text = 'Process All Images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Viewing_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end